classdef gta_plotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Plotter                       matlab.ui.Figure
        LoadDataButton                matlab.ui.control.Button
        ResultButton                  matlab.ui.control.StateButton
        Tree                          matlab.ui.container.Tree
        ProjectNode                   matlab.ui.container.TreeNode
        DatasetNode                   matlab.ui.container.TreeNode
        ModelsNode                    matlab.ui.container.TreeNode
        MfileNode                     matlab.ui.container.TreeNode
        PfileNode                     matlab.ui.container.TreeNode
        AfileNode                     matlab.ui.container.TreeNode
        ResultNode                    matlab.ui.container.TreeNode
        TabGroup                      matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        ModelTextAreaLabel            matlab.ui.control.Label
        ModelTextArea                 matlab.ui.control.TextArea
        ParameterTextAreaLabel        matlab.ui.control.Label
        ParameterTextArea             matlab.ui.control.TextArea
        Analysis_resultTextAreaLabel  matlab.ui.control.Label
        Analysis_resultTextArea       matlab.ui.control.TextArea
        IterationsSpinnerLabel        matlab.ui.control.Label
        IterationsSpinner             matlab.ui.control.Spinner
        StartAnalysisButton           matlab.ui.control.StateButton
        OpenProjectButton             matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        CreateProjectButton           matlab.ui.control.Button
        CurrentdirectoryLabel         matlab.ui.control.Label
        RefreshButton                 matlab.ui.control.StateButton
    end


   properties (Access = public) 
        
        filename
        data
        dataf
        wavelength
        time
        U
        S
        V
        chk
        f
        grid1
        p
        ax
        grid2
        lifetime
        das
        sas
        normdas
        normsas
        lsv
        rsv
        fitdata
        outdata
        rms
        conc
        outfilename
        kmat
        out_fig
        uit
        user_selected_fullpathname
        user_tableselect
    end


    methods (Access = public)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [filename,data,dataf,wavelength,time,U,S,V,chk] = gta_loaddataset;
            
%             f = app.BoardPanel;

            
            [grid1,p,ax,grid2,time] = gta_dataload_GUI_v1(filename,data,dataf,wavelength,time,U,S,V,chk);

        end

        % Value changed function: ResultButton
        function ResultButtonValueChanged(app, event)
        [wavelength,time,lifetime,das,sas,normdas,normsas,lsv,rsv,fitdata,outdata,rms,conc,outfilename,kmat] = readpyglot()
%          out_fig = app.Panel_2;
        
        gta_output(wavelength,das,sas,normdas,normsas,lsv,rsv,fitdata,outdata,rms,time,lifetime,conc,outfilename,kmat)
            
        end

        % Node text changed function: Tree
        function TreeNodeTextChanged(app, event)
            node = event.Node;
            
                display(node)
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            nod = selectedNodes.Text
             switch nod
                 case 'Dataset'
%                      app.BoardPanel.cla;
                     [filename,data,dataf,wavelength,time,U,S,V,chk] = gta_loaddataset;
                     t = app.TabGroup;
                     f = uitab(t);
                     f.Title = filename;
%                      f.SelectionType = 'Mouse'
                     [app.grid1,p,ax,grid2,time] = gta_dataload_GUI_v1(f,filename,data,dataf,wavelength,time,U,S,V,chk);
                 case 'Result'
%                       app.BoardPanel.cla;
                    [wavelength,time,lifetime,das,sas,normdas,normsas,lsv,rsv,fitdata,outdata,rms,conc,outfilename,kmat] = readpyglot()
                      t = app.TabGroup;
                     out_fig = uitab(t)
                     out_fig.Title = outfilename;
                     gta_output(out_fig,wavelength,das,sas,normdas,normsas,lsv,rsv,fitdata,outdata,rms,time,lifetime,conc,outfilename,kmat) 
                 case 'M-file'
                    [statusmodel,cmdoutmodel,filemodel]= gtaprintmodel()
                      app.ModelTextArea.Value = cmdoutmodel
                 case 'P-file'
                     [statuspar,cmdoutpar,fileparameter]= gtaprintparameter()
                      app.ParameterTextArea.Value = cmdoutpar
                 
                 case file_output
                     
                     [wavelength,time,lifetime,das,sas,normdas,normsas,lsv,rsv,fitdata,outdata,rms,conc,app.outfilename,kmat] = gta_nodeclickoutput(full_filename_output)
                          t = app.TabGroup;
                         out_fig = uitab(t)
                         out_fig.Title = file_output;
                        gta_output(out_fig,wavelength,das,sas,normdas,normsas,lsv,rsv,fitdata,outdata,rms,time,lifetime,conc,outfilename,kmat) 
                 
           
            end
        end

        % Value changed function: IterationsSpinner
        function IterationsSpinnerValueChanged(app, event)
            valueIt = app.IterationsSpinner.Value;
            
        end

        % Value changed function: StartAnalysisButton
        function StartAnalysisButtonValueChanged(app, event)
%             value = app.StartAnalysisButton.Value;
            valueIt = app.IterationsSpinner.Value;
            [statusoptimize,cmdoptimize,full_filename_analysis,full_filename_output,file_output]= gtaoptimize(valueIt)
            app.Analysis_resultTextArea.Value = cmdoptimize
             if statusoptimize == 0
            [wavelength,time,lifetime,das,sas,normdas,normsas,lsv,rsv,fitdata,outdata,rms,conc,outfilename,kmat] = gta_quickoutput(statusoptimize,full_filename_output)
              t = app.TabGroup;
             out_fig = uitab(t)
             out_fig.Title = file_output;
            gta_output(out_fig,wavelength,das,sas,normdas,normsas,lsv,rsv,fitdata,outdata,rms,time,lifetime,conc,outfilename,kmat) 
             else 
                 display('glotaran not working')
             end
%              storage = uitreenode(app.ProjectNode,app.ResultNode,"Text",file_output)
               storage = uitree("Position",[20 30 100 300],"SelectionChangedFcn",@resultnode)
               

        end

        % Button pushed function: OpenProjectButton
        function OpenProjectButtonPushed(app, event)
                        
            app.user_selected_fullpathname = uigetdir                  % Calls 'uigetdir' to obtain the directory location from the user
%             app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
%             app.UIFigure.Visible = 'on';      
            %app.DirectoryLocationLabel.Text=path;  % Sets the label text to be the selected path

            a = dir(app.user_selected_fullpathname);                         % Obtains the contents of the selected path.
            b={a(:).name}';                      % Gets the name of the files/folders of the contents and stores them appropriately in a cell array
            b(ismember(b,{'.','..'})) = [];      % Removes unnecessary '.' and '..' results from the display.

            app.uit = app.UITable;
            app.uit.Data = b
            app.uit.RowName = 'numbered'
            app.CurrentdirectoryLabel.Text= app.user_selected_fullpathname;
        end

        % Callback function
        function ButtonPushed(app, event)
%             indices = event.Indices;
%             display(indices)
%              row = eventdata.Indices(1)
%              col = eventdata.Indices(2)
        end

        % Cell selection callback: UITable
        function UITableCellSelection2(app, event)
            indices = event.Indices;
                  
            display(indices)
             row = event.Indices(1)
             col = event.Indices(2)
%               display(app.uit)
             app.user_tableselect = app.UITable.Data(row,col)
             [wavelength,time,lifetime,das,sas,normdas,normsas,lsv,rsv,fitdata,outdata,rms,conc,outfilename,kmat] = go2userchoice_output(app.user_selected_fullpathname,app.user_tableselect)  
             t = app.TabGroup;
             out_fig = uitab(t)
%              out_fig.Title = app.user_tableselect;
             gta_output(out_fig,wavelength,das,sas,normdas,normsas,lsv,rsv,fitdata,outdata,rms,time,lifetime,conc,outfilename,kmat) 
        
        end

        % Button pushed function: CreateProjectButton
        function CreateProjectButtonPushed(app, event)
                 [user_selected_filename,user_selected_fullpathname] = gta_userselected_outputpath
                  app.CurrentdirectoryLabel.Text= user_selected_fullpathname;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Plotter = uifigure('Visible', 'off');
            app.Plotter.Name = 'Plotter';
            app.LoadDataButton = uibutton(app.Plotter, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load-Data';
            app.ResultButton = uibutton(app.Plotter, 'state');
            app.ResultButton.ValueChangedFcn = createCallbackFcn(app, @ResultButtonValueChanged, true);
            app.ResultButton.Text = 'Result';
            app.Tree = uitree(app.Plotter);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.NodeTextChangedFcn = createCallbackFcn(app, @TreeNodeTextChanged, true);
            app.ProjectNode = uitreenode(app.Tree);
            app.ProjectNode.Text = 'Project';
            app.DatasetNode = uitreenode(app.ProjectNode);
            app.DatasetNode.Text = 'Dataset';
            app.ModelsNode = uitreenode(app.ProjectNode);
            app.ModelsNode.Text = 'Models';
            app.MfileNode = uitreenode(app.ModelsNode);
            app.MfileNode.Text = 'M-file';
            app.PfileNode = uitreenode(app.ModelsNode);
            app.PfileNode.Text = 'P-file';
            app.AfileNode = uitreenode(app.ModelsNode);
            app.AfileNode.Text = 'A-file';
            app.ResultNode = uitreenode(app.ProjectNode);
            app.ResultNode.Text = 'Result';
            app.TabGroup = uitabgroup(app.Plotter);
            app.Tab = uitab(app.TabGroup);
            app.ModelTextAreaLabel = uilabel(app.Tab);
            app.ModelTextAreaLabel.Text = 'Model ';
            app.ModelTextArea = uitextarea(app.Tab);
            app.ParameterTextAreaLabel = uilabel(app.Tab);
            app.ParameterTextAreaLabel.Text = 'Parameter';
            app.ParameterTextArea = uitextarea(app.Tab);
            app.Analysis_resultTextAreaLabel = uilabel(app.Tab);
            app.Analysis_resultTextAreaLabel.Text = 'Analysis_result';
            app.Analysis_resultTextArea = uitextarea(app.Tab);
            app.IterationsSpinnerLabel = uilabel(app.Plotter);
            app.IterationsSpinnerLabel.Text = 'Iterations';
            app.IterationsSpinner = uispinner(app.Plotter);
            app.IterationsSpinner.ValueChangedFcn = createCallbackFcn(app, @IterationsSpinnerValueChanged, true);
            app.StartAnalysisButton = uibutton(app.Plotter, 'state');
            app.StartAnalysisButton.ValueChangedFcn = createCallbackFcn(app, @StartAnalysisButtonValueChanged, true);
            app.StartAnalysisButton.Text = 'Start- Analysis';
            app.OpenProjectButton = uibutton(app.Plotter, 'push');
            app.OpenProjectButton.ButtonPushedFcn = createCallbackFcn(app, @OpenProjectButtonPushed, true);
            app.OpenProjectButton.Text = 'Open Project';
            app.UITable = uitable(app.Plotter);
            app.UITable.ColumnName = {'Files'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection2, true);
            app.CreateProjectButton = uibutton(app.Plotter, 'push');
            app.CreateProjectButton.ButtonPushedFcn = createCallbackFcn(app, @CreateProjectButtonPushed, true);
            app.CreateProjectButton.Text = 'Create Project';
            app.CurrentdirectoryLabel = uilabel(app.Plotter);
            app.CurrentdirectoryLabel.Text = 'Current-directory ';
            app.RefreshButton = uibutton(app.Plotter, 'state');
            app.RefreshButton.Text = 'Refresh';
            % Show the figure after all components are created
            app.Plotter.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gta_plotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Plotter)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Plotter)
        end
    end
end