classdef gta_plotter_v3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Plotter                     matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
        Menu3                       matlab.ui.container.Menu
        ParentTab                   matlab.ui.container.TabGroup
        AnalysisTab                 matlab.ui.container.Tab
        ContentPanel                matlab.ui.container.Panel
        AnalysisTableSelection      matlab.ui.control.Table
        FileIOPanel                 matlab.ui.container.Panel
        LoadButton                  matlab.ui.control.Button
        Button                      matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        ModelFileTextAreaLabel      matlab.ui.control.Label
        ModelFileTextArea           matlab.ui.control.TextArea
        ParameterFileTextAreaLabel  matlab.ui.control.Label
        ParameterFileTextArea       matlab.ui.control.TextArea
        DataFileTextAreaLabel       matlab.ui.control.Label
        DataFileTextArea            matlab.ui.control.TextArea
        AnalysisControlPanel        matlab.ui.container.Panel
        Button2                     matlab.ui.control.Button
        Button3                     matlab.ui.control.Button
        ResultsTab                  matlab.ui.container.Tab
        OutputTab                   matlab.ui.container.TabGroup
        Tab_2                       matlab.ui.container.Tab
        BrowseButton                matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        DataExplorationTab          matlab.ui.container.Tab
    end

    
    properties (Access = private)
        user_selected_fullpathname % Browse file pathname
        user_tableselect % table selection string by user
        wavelength
        time
        lifetime
        das
        sas
        normdas
        normsas
        lsv,
        rsv
        fitdata
        outdata
        rms
        conc
        outfilename
        kmat
        cmdoptimize
        t
        out_fig
        full_filename_output
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.user_selected_fullpathname = uigetdir();
            try
            a = dir(app.user_selected_fullpathname);
            catch
                message_nofile = sprintf(' No files selected ');
                uialert(app.Plotter,message_nofile,'Message',...
                    'Icon','none');
                return
            end
            b = {a(:).name}';
            b(ismember(b,{'.','..','count.mat','optimized_parameter.csv','result.md'})) = [];
%             app.uit = app.UITable;
            app.UITable.Data = b;
            app.UITable.RowName = 'numbered';
%             app.CurrentDirectory.Value = app.user_selected_fullpathname;
%             app.lbListBox.Items = b
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
%             indices = event.Indices;
            % Folder which contains .nc & .mat file for analysis summary
            % Folder segregation within the go2_userchoice_output_v4
            indices = event.Indices ;
            multi_select = numel(indices); % multiselection/comparision not implemented yet
            row = event.Indices(1);
            col = 1;
            app.user_tableselect = app.UITable.Data(row,col);
            % output plots / analysis output ?
            check = '.mat';
            if ~endsWith(app.user_tableselect,check)
                try
                % go2userchoice_output_v3 reads the nc file
                [app.wavelength,app.time,app.lifetime,app.das,app.sas,app.normdas,app.normsas,app.lsv,app.rsv,app.fitdata,app.outdata,app.rms,app.conc,app.outfilename,app.kmat] = go2userchoice_output_v4(app.full_filename_output,app.user_selected_fullpathname,app.user_tableselect);
                catch 
                    message_nofile = sprintf(' Cannot open-1 / No files found');
                   uialert(app.Plotter,message_nofile,'Error',...
                    'Icon','error'); 
                return
                end
                app.t = app.OutputTab;
                app.out_fig = uitab(app.t);
%                 app.OutputTab.UIContextMenu
                tabtitle = strcat(num2str(row),'.',string(app.user_tableselect));
                app.out_fig.Title = tabtitle;
                app.out_fig.ForegroundColor = 'r';
                gta_output(app.out_fig,app.wavelength,app.das,app.sas,app.normdas,app.normsas,app.lsv,app.rsv,app.fitdata,app.outdata,app.rms,app.time,app.lifetime,app.conc,app.outfilename,app.kmat,app.cmdoptimize,app.t,app.out_fig.Title);
             
            else
                % ends with .mat file
                
                cd(fullfile(app.user_selected_fullpathname))             
                app.t = app.ParentTab;
                app.out_fig = uitab(app.t); 
                % layout for plot and text area
                gg = uigridlayout(app.out_fig,[2 2]);              
                app.out_fig.Title = string(app.user_tableselect);
                tarea = uitextarea(gg);
                tarea.Layout.Column = 1;
                tarea.Layout.Row = [1 2];
                % save the matfile
                matfile = string(app.user_tableselect) ;
                res_opt = load(matfile);
                DataField = fieldnames(res_opt);
                % put the strings to text box
                tarea.Value = res_opt.(DataField{1});
                %tarea.Position = [10 10 600 700];
                tarea.FontColor = 'b';
                % create a text file
                textfile = erase(matfile,'.mat');
                textfilename = strcat(textfile,'.txt');
                % textfile created
                try
                writematrix(res_opt.(DataField{1}),textfilename,'Delimiter','tab')
                catch
                    message_nofile = res_opt.(DataField{1});
                    uialert(app.Plotter,message_nofile,'Error',...
                        'Icon','error');
                    delete(textfilename)
                    
%                     delete(textfile2)
                    return
                end
                    
                % look for the line numbers which will give the iterations details
                A1 = regexp(fileread(textfilename),'\n','split');
                start = find(contains(A1,'Iteration'))+1;
                upto = find(contains(A1,'maximum')) - 1; % doesnot work if mimimzation reached
                if isempty(upto) % when iterations has satisfies mimization function
                    upto = find(contains(A1,'termination')) - 1;
                end
                % reopen the textfile
                fid = fopen(textfilename,'r');
                str = textscan(fid,'%s','Delimiter','\n');
                fclose(fid);
                str2 = str{1}(start:upto);             
                %% Save as a new text file
                textfile2 = strcat(textfile,'final','.txt');
                fid2 = fopen(textfile2,'w');
                fprintf(fid2,'%s\n', str2{:});
                fclose(fid2);
                try
                A2 = dlmread(textfile2); % saved in A2 for plot
                catch
                    message_nofile = res_opt.(DataField{1});
                    uialert(app.Plotter,message_nofile,'Error',...
                        'Icon','error');
                    delete(textfilename)
                    delete(textfile2)
                    
                    return
                end
                %% plot
                ax0 = uiaxes(gg);               
                ax0.Layout.Column = 2;
                ax0.Layout.Row = 1;
                plot(ax0,A2(:,2),(A2(:,3)),'k','Marker','o','LineWidth',1.5,'MarkerEdgeColor','k','MarkerSize',10,'MarkerFaceColor','k');
                ax0.Title.String = 'Iterational changes';
                xlabel(ax0,'Iterations');
                ylabel(ax0,'Cost');
                legend(ax0,strcat('Total Iterations-',num2str(size(A2,1))));
                ax0.Box = 'on'; ax0.XGrid = 'on';ax0.YGrid = 'on';
                delete(textfilename)
                delete(textfile2)   
            end
        end

        % Callback function
        function lbListBoxValueChanged(app, event)
            value = app.lbListBox.Value
%             alldata = '<all data>';
%             if numel(app.DatasetObjectsListBox.Items) == 1
%                 app.DatasetObjectsListBox.Value = app.DatasetObjectsListBox.Items(1);
%             end
%             var = app.DatasetObjectsListBox.Value;
%                    var = app.lbListBox.Value
%             if numel(var) > 1
%                 % If multiple selection then only specdata objects count
%                 var = var(isspecdata(app,var));
%             end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            try
                [app.user_selected_fullpathname,openname] = gta_userselected_outputpath;
            catch
                message_cancelopen = sprintf('Output folder not created.');
                uialert(app.Plotter,message_cancelopen,'Message',...
                    'Icon','none');
                return
            end


            if ~isspace(app.user_selected_fullpathname)
                %                 app.CurrentDirectory.Value = app.user_selected_fullpathname;
                %                 app.CreateProjectTextArea.Value = app.user_selected_fullpathname;
                ny = dir('*.yaml');
                if size(ny,1)>0
                    by = {ny(:).name}';
                    by(ismember(by,{'.','..','count.mat','optimized_parameter.csv','result.md'})) = [];
                    %             app.uit = app.UITable;
                    app.AnalysisTableSelection.Data = by;
                    app.AnalysisTableSelection.RowName = 'numbered';
                end

            else
                outfoldermsg1 = sprintf('Path to filename must be without spaces: \n ');
                outfoldermsg2 = app.user_selected_fullpathname;
                outfoldermsg = strcat(outfoldermsg1,outfoldermsg2);
                uialert(app.Plotter,outfoldermsg,'WRONG FILE NAMING',...
                    'Icon','error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Plotter = uifigure('Visible', 'off');
            app.Plotter.Name = 'UI Figure';
            app.Menu = uimenu(app.Plotter);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.Plotter);
            app.Menu2.Text = 'Menu2';
            app.Menu3 = uimenu(app.Plotter);
            app.Menu3.Text = 'Menu3';
            app.ParentTab = uitabgroup(app.Plotter);
            app.AnalysisTab = uitab(app.ParentTab);
            app.AnalysisTab.Title = 'Analysis';
            app.ContentPanel = uipanel(app.AnalysisTab);
            app.ContentPanel.Title = 'Content';
            app.AnalysisTableSelection = uitable(app.ContentPanel);
            app.AnalysisTableSelection.ColumnName = {'Column 1'};
            app.AnalysisTableSelection.RowName = {};
            app.FileIOPanel = uipanel(app.AnalysisTab);
            app.FileIOPanel.Title = 'File I/O';
            app.LoadButton = uibutton(app.FileIOPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Icon = 'open.png';
            app.LoadButton.Text = 'Load';
            app.Button = uibutton(app.FileIOPanel, 'push');
            app.Panel = uipanel(app.AnalysisTab);
            app.Panel.Title = 'Panel';
            app.ModelFileTextAreaLabel = uilabel(app.Panel);
            app.ModelFileTextAreaLabel.Text = 'Model File';
            app.ModelFileTextArea = uitextarea(app.Panel);
            app.ParameterFileTextAreaLabel = uilabel(app.Panel);
            app.ParameterFileTextAreaLabel.Text = 'Parameter File';
            app.ParameterFileTextArea = uitextarea(app.Panel);
            app.DataFileTextAreaLabel = uilabel(app.Panel);
            app.DataFileTextAreaLabel.Text = 'Data File';
            app.DataFileTextArea = uitextarea(app.Panel);
            app.AnalysisControlPanel = uipanel(app.AnalysisTab);
            app.AnalysisControlPanel.Title = 'Analysis Control';
            app.Button2 = uibutton(app.AnalysisControlPanel, 'push');
            app.Button2.Text = 'Button2';
            app.Button3 = uibutton(app.AnalysisControlPanel, 'push');
            app.Button3.Text = 'Button3';
            app.ResultsTab = uitab(app.ParentTab);
            app.ResultsTab.Title = 'Results';
            app.OutputTab = uitabgroup(app.ResultsTab);
            app.Tab_2 = uitab(app.OutputTab);
            app.BrowseButton = uibutton(app.ResultsTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Icon = 'open.png';
            app.BrowseButton.Text = 'Browse';
            app.UITable = uitable(app.ResultsTab);
            app.UITable.ColumnName = {'Column 1'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.DataExplorationTab = uitab(app.ParentTab);
            app.DataExplorationTab.Title = 'Data-Exploration';
            % Show the figure after all components are created
            app.Plotter.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gta_plotter_v3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Plotter)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Plotter)
        end
    end
end